classdef Graph_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        SweepNumberEditField  matlab.ui.control.NumericEditField
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_4              matlab.ui.control.UIAxes
        SweepNumberLabel      matlab.ui.control.Label
        UIAxes_5              matlab.ui.control.UIAxes
        UIAxes_6              matlab.ui.control.UIAxes
        AnalyzeCh1Button      matlab.ui.control.Button
        AnalyzeCh2Button      matlab.ui.control.Button
        AnalyzeCh3Button      matlab.ui.control.Button
        AnalyzeCh4Button      matlab.ui.control.Button
        AnalyzeCh5Button      matlab.ui.control.Button
        AnalyzeCh6Button      matlab.ui.control.Button
    end

    
    properties (Access = private) % Properties allows for the saving of variables, 
        % to call the saved variables just put app.(variable name)
        D;value;x; % Data
    end
    
    methods (Access = private) % This is the helper function - this allows for multiple functions
        % to be created and stored and can later be called in callbacks
        
        function LoadData(app)
            temp = load(uigetfile,'trials');
            app.D = temp.trials;
            x = (-.1:1/999:.30)';
            plot(app.UIAxes,x,app.D.ch1{1,1});
            plot(app.UIAxes_2,x,app.D.ch2{1,1});
            plot(app.UIAxes_3,x,app.D.ch3{1,1});
            plot(app.UIAxes_4,x,app.D.ch4{1,1});
            plot(app.UIAxes_5,x,app.D.ch5{1,1});
            plot(app.UIAxes_6,x,app.D.ch6{1,1});
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LoadData(app)
        end

        % Callback function
        function ArtefactButtonPushed(app, event)
%             artgraph1(app)
        end

        % Callback function
        function ClearButtonPushed(app, event)
%             deletegraph1(app)
        end

        % Callback function
        function ArtefactButton_2Pushed(app, event)
%             artgraph2(app)
        end

        % Callback function
        function ClearButton_2Pushed(app, event)
%             deletegraph2(app)
        end

        % Value changed function: SweepNumberEditField
        function SweepNumberEditFieldValueChanged(app, event)
                               
          app.value = app.SweepNumberEditField.Value;
                reset(app.UIAxes);
                reset(app.UIAxes_2);
                reset(app.UIAxes_3);
                reset(app.UIAxes_4);
                reset(app.UIAxes_5);
                reset(app.UIAxes_6);
                
                app.x = (-.1:1/999:.30)';
                
                % plotting Channels
%                 
%                 plot(app.UIAxes_2,x,app.D.ch2{app.value,1});
%                 plot(app.UIAxes_3,x,app.D.ch3{app.value,1});
%                 plot(app.UIAxes_4,x,app.D.ch4{app.value,1});
%                 plot(app.UIAxes_5,x,app.D.ch5{app.value,1});
%                 plot(app.UIAxes_6,x,app.D.ch6{app.value,1});
                
                % plotting MEP Onset
                
%                 b2 = app.D.ch2_MEP_time(app.value);
%                 b3 = app.D.ch3_MEP_time(app.value);
%                 b4 = app.D.ch4_MEP_time(app.value);
%                 b5 = app.D.ch5_MEP_time(app.value);
%                 b6 = app.D.ch6_MEP_time(app.value);

%                 
%                 hold(app.UIAxes_2);
%                 hold(app.UIAxes_3);
%                 hold(app.UIAxes_4);
%                 hold(app.UIAxes_5);
%                 hold(app.UIAxes_6);
                
                
%                 xline(app.UIAxes_2,b2,'green');
%                 xline(app.UIAxes_3,b3,'green');
%                 xline(app.UIAxes_4,b4,'green');
%                 xline(app.UIAxes_5,b5,'green');
%                 xline(app.UIAxes_6,b6,'green');
                
                % plotting MEP Offset
                
%                 d2 = app.D.ch2_MEP_offset(app.value);
%                 d3 = app.D.ch3_MEP_offset(app.value);
%                 d4 = app.D.ch4_MEP_offset(app.value);
%                 d5 = app.D.ch5_MEP_offset(app.value);
%                 d6 = app.D.ch6_MEP_offset(app.value);
        
                
%                 xline(app.UIAxes_2,d2,'red');
%                 xline(app.UIAxes_3,d3,'red')
%                 xline(app.UIAxes_4,d4,'red');
%                 xline(app.UIAxes_5,d5,'red');
%                 xline(app.UIAxes_6,d6,'red');

                % Plotting Artefact ------------ [ARTLOC -column 8] This needs to be changed
                % eventually
                
                
%                 xline(app.UIAxes_2,app.D.artloc(app.value),'--b');
%                 xline(app.UIAxes_3,app.D.artloc(app.value),'--b');
%                 xline(app.UIAxes_4,app.D.artloc(app.value),'--b');
%                 xline(app.UIAxes_5,app.D.artloc(app.value),'--b');
%                 xline(app.UIAxes_6,app.D.artloc(app.value),'--b');
                                
                % Legend
                
%                 legend(app.UIAxes_2,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
%                 legend(app.UIAxes_3,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
%                 legend(app.UIAxes_4,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
%                 legend(app.UIAxes_5,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
%                 legend(app.UIAxes_6,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');

        end

        % Callback function
        function ArtefactButton_3Pushed(app, event)
%             artgraph3(app)
        end

        % Callback function
        function ArtefactButton_4Pushed(app, event)
%             artgraph4(app)
        end

        % Callback function
        function ClearButton_3Pushed(app, event)
%             deletegraph3(app)
        end

        % Callback function
        function ClearButton_4Pushed(app, event)
%             deletegraph4(app)
        end

        % Callback function
        function ArtefactButton_5Pushed(app, event)
%             artgraph5(app)
        end

        % Callback function
        function ArtefactButton_6Pushed(app, event)
%             artgraph6(app)
        end

        % Callback function
        function ClearButton_5Pushed(app, event)
%             deletegraph5(app)
        end

        % Callback function
        function ClearButton_6Pushed(app, event)
%             deletegraph6(app)
        end

        % Callback function
        function MEP_OnsetCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function MEP_OffsetCheckBoxValueChanged(app, event)
          
        end

        % Button pushed function: AnalyzeCh1Button
        function AnalyzeCh1ButtonPushed(app, event)
            plot(app.UIAxes,app.x,app.D.ch1{app.value,1});
            b1 = app.D.ch1_MEP_time(app.value);
            hold(app.UIAxes);
            xline(app.UIAxes,b1,'green');
            d1 = app.D.ch1_MEP_offset(app.value);
            xline(app.UIAxes,d1,'red');
            xline(app.UIAxes,app.D.artloc(app.value),'--b');
            legend(app.UIAxes,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
        end

        % Button pushed function: AnalyzeCh2Button
        function AnalyzeCh2ButtonPushed(app, event)
            plot(app.UIAxes_2,app.x,app.D.ch2{app.value,1});
            b1 = app.D.ch2_MEP_time(app.value);
            hold(app.UIAxes);
            xline(app.UIAxes,b1,'green');
            d1 = app.D.ch2_MEP_offset(app.value);
            xline(app.UIAxes,d1,'red');
            xline(app.UIAxes,app.D.artloc(app.value),'--b');
            legend(app.UIAxes,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
        end

        % Button pushed function: AnalyzeCh3Button
        function AnalyzeCh3ButtonPushed(app, event)
            plot(app.UIAxes_3,app.x,app.D.ch3{app.value,1});
            b1 = app.D.ch3_MEP_time(app.value);
            hold(app.UIAxes);
            xline(app.UIAxes,b1,'green');
            d1 = app.D.ch3_MEP_offset(app.value);
            xline(app.UIAxes,d1,'red');
            xline(app.UIAxes,app.D.artloc(app.value),'--b');
            legend(app.UIAxes,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
        end

        % Button pushed function: AnalyzeCh4Button
        function AnalyzeCh4ButtonPushed(app, event)
            plot(app.UIAxes_4,app.x,app.D.ch4{app.value,1});
            b1 = app.D.ch4_MEP_time(app.value);
            hold(app.UIAxes);
            xline(app.UIAxes,b1,'green');
            d1 = app.D.ch4_MEP_offset(app.value);
            xline(app.UIAxes,d1,'red');
            xline(app.UIAxes,app.D.artloc(app.value),'--b');
            legend(app.UIAxes,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
        end

        % Button pushed function: AnalyzeCh5Button
        function AnalyzeCh5ButtonPushed(app, event)
            plot(app.UIAxes_5,app.x,app.D.ch5{app.value,1});
            b1 = app.D.ch5_MEP_time(app.value);
            hold(app.UIAxes);
            xline(app.UIAxes,b1,'green');
            d1 = app.D.ch5_MEP_offset(app.value);
            xline(app.UIAxes,d1,'red');
            xline(app.UIAxes,app.D.artloc(app.value),'--b');
            legend(app.UIAxes,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
        end

        % Button pushed function: AnalyzeCh6Button
        function AnalyzeCh6ButtonPushed(app, event)
            plot(app.UIAxes_6,app.x,app.D.ch6{app.value,1});
            b1 = app.D.ch6_MEP_time(app.value);
            hold(app.UIAxes);
            xline(app.UIAxes,b1,'green');
            d1 = app.D.ch6_MEP_offset(app.value);
            xline(app.UIAxes,d1,'red');
            xline(app.UIAxes,app.D.artloc(app.value),'--b');
            legend(app.UIAxes,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Channel 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Channel 2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.SweepNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.SweepNumberEditField.ValueChangedFcn = createCallbackFcn(app, @SweepNumberEditFieldValueChanged, true);
            app.SweepNumberEditField.Value = 1;
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Channel 3')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Figure 4')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.SweepNumberLabel = uilabel(app.UIFigure);
            app.SweepNumberLabel.Text = 'Sweep Number';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Channel 5')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Channel 6')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            app.AnalyzeCh1Button = uibutton(app.UIFigure, 'push');
            app.AnalyzeCh1Button.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeCh1ButtonPushed, true);
            app.AnalyzeCh1Button.Text = 'Analyze Ch 1';
            app.AnalyzeCh2Button = uibutton(app.UIFigure, 'push');
            app.AnalyzeCh2Button.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeCh2ButtonPushed, true);
            app.AnalyzeCh2Button.Text = 'Analyze Ch 2';
            app.AnalyzeCh3Button = uibutton(app.UIFigure, 'push');
            app.AnalyzeCh3Button.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeCh3ButtonPushed, true);
            app.AnalyzeCh3Button.Text = 'Analyze Ch 3';
            app.AnalyzeCh4Button = uibutton(app.UIFigure, 'push');
            app.AnalyzeCh4Button.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeCh4ButtonPushed, true);
            app.AnalyzeCh4Button.Text = 'Analyze Ch 4';
            app.AnalyzeCh5Button = uibutton(app.UIFigure, 'push');
            app.AnalyzeCh5Button.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeCh5ButtonPushed, true);
            app.AnalyzeCh5Button.Text = 'Analyze Ch 5';
            app.AnalyzeCh6Button = uibutton(app.UIFigure, 'push');
            app.AnalyzeCh6Button.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeCh6ButtonPushed, true);
            app.AnalyzeCh6Button.Text = 'Analyze Ch 6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Graph_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end